classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RunButton                  matlab.ui.control.Button
        FolderEditField            matlab.ui.control.EditField
        UsecustomdatasetLabel      matlab.ui.control.Label
        UseCustomDataSwitch        matlab.ui.control.Switch
        PresetsButtonGroup         matlab.ui.container.ButtonGroup
        SetAButton                 matlab.ui.control.RadioButton
        SetBButton                 matlab.ui.control.RadioButton
        SetCButton                 matlab.ui.control.RadioButton
        FolderLabel                matlab.ui.control.Label
        ErrorValueEditFieldLabel   matlab.ui.control.Label
        ErrorValueEditField        matlab.ui.control.NumericEditField
        PlayAnimationButton        matlab.ui.control.Button
        ThresholdMaskButton        matlab.ui.control.Button
        HarrisDetectionButton      matlab.ui.control.Button
        CorrespondingPointsButton  matlab.ui.control.Button
        DemosLabel                 matlab.ui.control.Label
        Felix12200299Label         matlab.ui.control.Label
        Liam97117877Label          matlab.ui.control.Label
        Martin13029855Label        matlab.ui.control.Label
        Jonathan11393269Label      matlab.ui.control.Label
        CreatedbyLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        aligned_images; %    Aligned images returned from main
        error_value; %    Value returned from error statement
        original_images; %    Original dataset
    end
    
    methods (Access = private)
        
        %    Return directory location of the provided datasets
        function folder_location = get_preset_dataset_directory(app)
            %    PRESET_DIR must be within the MatLab working folder
            PRESET_DIR = './Dynamic Thermographic Images/';
            
            %    Dataset names must end with a /
            SETA_NAME = 'T0004/';
            SETB_NAME = 'T0005/';
            SETC_NAME = 'T0007/';
            
            %    If radio button is enabled, use that dataset
            if (app.SetAButton.Value == 1)
                folder_location = strcat(PRESET_DIR, SETA_NAME);
            elseif (app.SetBButton.Value == 1)
                folder_location = strcat(PRESET_DIR, SETB_NAME);
            elseif (app.SetCButton.Value == 1)
                folder_location = strcat(PRESET_DIR, SETC_NAME);
            end
            
        end
        
        
        %    Display error prompt from provided parameter
        function show_error_prompt(app, errorstring)
            errordlg(errorstring);
        end
        
        
        %    Return true if directory is in appropriate format, else return false
        function valid = is_directory_valid(app, directory)
            
            %    ./Folder/Folder/
            expression = '^\.(\/[\w ]+)+\/$';
            
            out = regexp(directory, expression, 'end', 'once');
            
            if (out == length(directory))
                valid = true;
            else
                valid = false;
            end
        end
        
        
        %    Call main function for preset datasets of program and return original dataset, aligned images set, and error value
        function [originalImages, alignedImages, error] = use_preset_dataset(app)
            [originalImages, alignedImages, error] = main(get_preset_dataset_directory(app));
        end
        
        
        %    Disable UI elements when program is run
        function start_run(app)
            set(app.RunButton, 'enable', 'off');
            set(app.UseCustomDataSwitch, 'enable', 'off');
            set(app.PlayAnimationButton, 'enable', 'off');
            set(app.ThresholdMaskButton, 'enable', 'off');
            set(app.HarrisDetectionButton, 'enable', 'off');
            set(app.CorrespondingPointsButton, 'enable', 'off');
        end
        
        
        %    Enable UI elements after program is run
        function end_run(app, original, result, error)
            app.original_images = original;
            app.aligned_images = result;
            app.error_value = error;
            set(app.UseCustomDataSwitch, 'enable', 'on');
            set(app.ErrorValueEditField, 'value', error);
            set(app.PlayAnimationButton, 'enable', 'on');
            set(app.RunButton, 'enable', 'on');
            set(app.ThresholdMaskButton, 'enable', 'on');
            set(app.HarrisDetectionButton, 'enable', 'on');
            set(app.CorrespondingPointsButton, 'enable', 'on');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.SetAButton, 'enable', 'on');
            set(app.SetBButton, 'enable', 'on');
            set(app.SetCButton, 'enable', 'on');
            set(app.FolderEditField, 'enable', 'off');
            set(app.FolderEditField, 'editable', 'off');
            set(app.FolderLabel, 'enable', 'off');
            set(app.PlayAnimationButton, 'enable', 'off');
            set(app.ThresholdMaskButton, 'enable', 'on');
            set(app.HarrisDetectionButton, 'enable', 'on');
            set(app.CorrespondingPointsButton, 'enable', 'on');
        end

        % Button pushed function: CorrespondingPointsButton
        function CorrespondingPointsButtonPushed(app, event)
            files = {readFiles(get_preset_dataset_directory(app))};
            sampleFunctions(files, 'corresponding'); 
        end

        % Button pushed function: HarrisDetectionButton
        function HarrisDetectionButtonPushed(app, event)
            files = {readFiles(get_preset_dataset_directory(app))};
            sampleFunctions(files, 'hcd');
        end

        % Button pushed function: ThresholdMaskButton
        function ThresholdMaskButtonPushed(app, event)
            files = {readFiles(get_preset_dataset_directory(app))};
            sampleFunctions(files, 'mask');
        end

        % Button pushed function: PlayAnimationButton
        function play_animation(app, event)
            %    Animate aligned images for ONE loop and THREE frames per second
            animate(app.aligned_images, 1, 3);
        end

        % Button pushed function: RunButton
        function run(app, event)
            start_run(app);
            if strcmp(app.UseCustomDataSwitch.Value, 'On')
                %    Use custom folder
                folder_location = app.FolderEditField.Value;
                
                if is_directory_valid(app, folder_location)
                    [original, result, error] = main(folder_location);
                    end_run(app, original, result, error);
                else
                    show_error_prompt(app, 'Please enter valid directory.  Expecting format ./Folder/Folder/');
                    %    Reenable RUN button to allow program to be rerun
                    set(app.RunButton, 'enable', 'on');
                    set(app.UseCustomDataSwitch, 'enable', 'on');
                end
            else
                %    Use preset dataset
                [original, result, error] = use_preset_dataset(app);
                end_run(app, original, result, error);
            end
        end

        % Value changed function: UseCustomDataSwitch
        function toggle_dataset(app, event)
            value = app.UseCustomDataSwitch.Value;
            
            if strcmp(value, 'On')
                set(app.SetAButton, 'enable', 'off');
                set(app.SetBButton, 'enable', 'off');
                set(app.SetCButton, 'enable', 'off');
                set(app.ThresholdMaskButton, 'enable', 'off');
                set(app.HarrisDetectionButton, 'enable', 'off');
                set(app.CorrespondingPointsButton, 'enable', 'off');
                set(app.FolderEditField, 'enable', 'on');
                set(app.FolderEditField, 'editable', 'on');
                set(app.FolderLabel, 'enable', 'on');
            elseif strcmp(value, 'Off')
                set(app.SetAButton, 'enable', 'on');
                set(app.SetBButton, 'enable', 'on');
                set(app.SetCButton, 'enable', 'on');
                set(app.ThresholdMaskButton, 'enable', 'on');
                set(app.HarrisDetectionButton, 'enable', 'on');
                set(app.CorrespondingPointsButton, 'enable', 'on');
                set(app.FolderEditField, 'enable', 'off');
                set(app.FolderEditField, 'editable', 'off');
                set(app.FolderLabel, 'enable', 'off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunButton.Text = 'Run';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.FolderEditField.Enable = 'off';
            app.UsecustomdatasetLabel = uilabel(app.UIFigure);
            app.UsecustomdatasetLabel.Text = 'Use custom dataset';
            app.UseCustomDataSwitch = uiswitch(app.UIFigure, 'slider');
            app.UseCustomDataSwitch.ValueChangedFcn = createCallbackFcn(app, @toggle_dataset, true);
            app.PresetsButtonGroup = uibuttongroup(app.UIFigure);
            app.PresetsButtonGroup.Title = 'Presets';
            app.SetAButton = uiradiobutton(app.PresetsButtonGroup);
            app.SetAButton.Text = 'Set A';
            app.SetAButton.Value = true;
            app.SetBButton = uiradiobutton(app.PresetsButtonGroup);
            app.SetBButton.Text = 'Set B';
            app.SetCButton = uiradiobutton(app.PresetsButtonGroup);
            app.SetCButton.Text = 'Set C';
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Text = 'Folder:';
            app.ErrorValueEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorValueEditFieldLabel.Text = 'Error Value';
            app.ErrorValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorValueEditField.ValueDisplayFormat = '%11.10g';
            app.ErrorValueEditField.Editable = 'off';
            app.PlayAnimationButton = uibutton(app.UIFigure, 'push');
            app.PlayAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @play_animation, true);
            app.PlayAnimationButton.Text = 'Play Animation';
            app.ThresholdMaskButton = uibutton(app.UIFigure, 'push');
            app.ThresholdMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdMaskButtonPushed, true);
            app.ThresholdMaskButton.Text = 'Threshold Mask';
            app.HarrisDetectionButton = uibutton(app.UIFigure, 'push');
            app.HarrisDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @HarrisDetectionButtonPushed, true);
            app.HarrisDetectionButton.Text = 'Harris Detection';
            app.CorrespondingPointsButton = uibutton(app.UIFigure, 'push');
            app.CorrespondingPointsButton.ButtonPushedFcn = createCallbackFcn(app, @CorrespondingPointsButtonPushed, true);
            app.CorrespondingPointsButton.Text = 'Corresponding Points';
            app.DemosLabel = uilabel(app.UIFigure);
            app.DemosLabel.Text = 'Demos';
            app.Felix12200299Label = uilabel(app.UIFigure);
            app.Felix12200299Label.Text = 'Felix - 12200299';
            app.Liam97117877Label = uilabel(app.UIFigure);
            app.Liam97117877Label.Text = 'Liam - 97117877';
            app.Martin13029855Label = uilabel(app.UIFigure);
            app.Martin13029855Label.Text = 'Martin - 13029855';
            app.Jonathan11393269Label = uilabel(app.UIFigure);
            app.Jonathan11393269Label.Text = 'Jonathan - 11393269';
            app.CreatedbyLabel = uilabel(app.UIFigure);
            app.CreatedbyLabel.Text = 'Created by:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end